classdef mainAPP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        TabGroup                   matlab.ui.container.TabGroup
        BirdsounddenoisingTab      matlab.ui.container.Tab
        AudioloadButton            matlab.ui.control.Button
        StopButton                 matlab.ui.control.Button
        PlaythesoundButton         matlab.ui.control.Button
        ChoosesignalDropDown       matlab.ui.control.DropDown
        ChoosesignalLabel          matlab.ui.control.Label
        UIAxes_sig_f               matlab.ui.control.UIAxes
        UIAxes_sig_t               matlab.ui.control.UIAxes
        PianonotesmappingTab       matlab.ui.container.Tab
        BeatsTextArea              matlab.ui.control.TextArea
        BeatsTextAreaLabel         matlab.ui.control.Label
        NotesTextArea              matlab.ui.control.TextArea
        NotesTextAreaLabel         matlab.ui.control.Label
        GeneratenotesbeatsButton   matlab.ui.control.Button
        MaptopianofrequencyButton  matlab.ui.control.Button
        ExtractthefundamentalfrequencyButton  matlab.ui.control.Button
        UIAxes_notes               matlab.ui.control.UIAxes
        UIAxes_fmax                matlab.ui.control.UIAxes
        PlaythemelodyTab           matlab.ui.container.Tab
        FasterversionsimilartothebirdsoundButton  matlab.ui.control.Button
        PlaythepianoButton         matlab.ui.control.Button
        UIAxes_piano               matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Fs = 44100;   % Sampling frequency
        Bird_sound    % bird sound signal
        Fc1 = 3200;   % Cut-off frequency 1
        Fc2 = 5000;   % Cut-off frequency 2
        N             % length of the sample
        Bird_filtered % Filtered bird sound signal
        t             % Time duration
        spectro       % Spectrogram of the filtered bird sound
        f_max         % Maximum frequency component
        note_origin
        t_origin
        notes
        beats
        piano_sig
        piano_fast
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: ChoosesignalDropDown
        function ChoosesignalDropDownValueChanged(app, event)
            value = app.ChoosesignalDropDown.Value;
            if value == "Original"
                cla(app.UIAxes_sig_t); cla(app.UIAxes_sig_f);
                % app.UIAxes_sig_t.plot(t, app.Bird_sound);
                plot(app.UIAxes_sig_t, app.t, app.Bird_sound);
                y = fft(app.Bird_sound);
                app.Bird_filtered = app.Bird_sound;
                % disp(app.Fs); disp(app.N);
                f = app.Fs/app.N*(0:round(app.N/2)-1); %half of frequency points, T * Fs = N = Fs/fs 
                plot(app.UIAxes_sig_f, f, abs(y(1:round(app.N/2))));
                disp("Clicked original");
            elseif value == "Ideal BPF"
                cla(app.UIAxes_sig_t); cla(app.UIAxes_sig_f);
                [app.Bird_filtered, f, y] = IdealBPF(app.Bird_sound, app.Fc1, app.Fc2, app.Fs);
                plot(app.UIAxes_sig_t, app.t, app.Bird_filtered);
                plot(app.UIAxes_sig_f, f, abs(y));
                disp("Clicked Ideal BPF");
            elseif value == "FIR BPF"
                cla(app.UIAxes_sig_t); cla(app.UIAxes_sig_f);
                [app.Bird_filtered, N_f] = FIR_BPF(app.Bird_sound, app.Fc1, app.Fc2, app.Fs);
                t_f = (0:N_f-1)/app.Fs;
                plot(app.UIAxes_sig_t, t_f(1:app.N), app.Bird_filtered(1:app.N));
                y = fft(app.Bird_filtered);
                f = app.Fs/N_f*(0:round(N_f/2)-1);
                plot(app.UIAxes_sig_f, f, abs(y(1:round(N_f/2))));
                app.Bird_filtered = app.Bird_filtered(1:app.N);
                disp("Clicked FIR BPF");
            elseif value == "Scalart (Wiener) Filter"
                cla(app.UIAxes_sig_t); cla(app.UIAxes_sig_f);
                app.Bird_filtered = WienerScalart96(app.Bird_sound, app.Fs, 1);
                N_f = length(app.Bird_filtered);
                app.Bird_filtered(N_f+1:app.N) = 0;
                plot(app.UIAxes_sig_t, app.t, app.Bird_filtered.');
                y = fft(app.Bird_filtered);
                f = app.Fs/app.N*(0:round(app.N/2)-1);
                plot(app.UIAxes_sig_f, f, abs(y(1:round(app.N/2))));
                disp("Clicked Scalart (Wiener) Filter");
            end
        end

        % Button pushed function: PlaythesoundButton
        function PlaythesoundButtonPushed(app, event)
%             if app.PlaythesoundButton.Text == "Play the sound"
            soundsc(app.Bird_filtered, app.Fs);
%                 app.PlaythesoundButton.Text = "Stop";
%             elseif app.PlaythesoundButton.Text == "Stop"
%                 clear sound;
%                 app.PlaythesoundButton.Text = "Play the sound";
%             end
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            clear sound;
        end

        % Button pushed function: ExtractthefundamentalfrequencyButton
        function ExtractthefundamentalfrequencyButtonPushed(app, event)
            app.spectro = spectrogram(app.Bird_filtered, window(@hann, 1000), 500, 1024, app.Fs,'yaxis');
            [app.note_origin, app.f_max] = SoundToNote(app.Bird_filtered, app.Fs);
            app.t_origin = app.t(500:500:app.N-500);
            plot(app.UIAxes_fmax, app.t_origin, app.f_max);
            xticks(app.UIAxes_fmax, 0:0.5:3);
            % xticklabels(app.UIAxes_fmax, num2str(0:0.5:3));
        end

        % Button pushed function: MaptopianofrequencyButton
        function MaptopianofrequencyButtonPushed(app, event)
            index=find(app.note_origin>0);
            app.note_origin=app.note_origin(index);%only keep the sounds part
            app.t_origin = app.t_origin(index);
            plot(app.UIAxes_notes, app.t_origin, app.note_origin);
            xticks(app.UIAxes_notes, 1:0.2:2);
            % xticklabels(app.UIAxes_notes, num2str(1:0.2:2));
        end

        % Button pushed function: GeneratenotesbeatsButton
        function GeneratenotesbeatsButtonPushed(app, event)
            
            [app.notes,app.beats]=CreateBeats(app.note_origin);
            app.NotesTextArea.Value = num2str(app.notes);
            % disp("beats"); disp(app.beats);
            app.BeatsTextArea.Value = num2str(app.beats);
            disp("Notes and beats generated.");
        end

        % Button pushed function: PlaythepianoButton
        function PlaythepianoButtonPushed(app, event)
%             if app.PlaythepianoButton.Text == "Play the piano!"
            app.piano_sig = PlayPiano(app.notes, app.Fs, app.beats);
            soundsc(app.piano_sig, app.Fs);
            N_sig = length(app.piano_sig);
            t_piano = 0:1/app.Fs:(N_sig-1)/app.Fs;
            plot(app.UIAxes_piano, t_piano, app.piano_sig);
            disp("Play the piano!");
%                 app.PlaythepianoButton.Text = "Stop";
%             elseif app.PlaythepianoButton.Text == "Stop"
%                 clear sound;
%                 app.PlaythepianoButton.Text = "Play the piano!";
%             end
        end

        % Button pushed function: AudioloadButton
        function AudioloadButtonPushed(app, event)
            samples = [1,3*app.Fs];
            [app.Bird_sound,app.Fs]=audioread('XC689075_Systellura_longirostris.mp3',samples);
            app.Bird_sound = app.Bird_sound';
            app.N = length(app.Bird_sound); %number of samples
            app.t = (0:app.N-1)/app.Fs; %time
            disp("Audio loaded.");
        end

        % Callback function
        function SpectrogramTabSizeChanged(app, event)
%             position = app.SpectrogramTab.Position;
%             disp("Position"); disp(position);
            % app.spectro = spectrogram(app.Bird_filtered, window(@hann, 1000), 500, 1024, app.Fs,'yaxis');
            % spectrogram(app.UIAxes_spectrogram, app.Bird_filtered, 'yaxis');
        end

        % Callback function
        function PlotspectrogramButtonPushed(app, event)
%             app.spectro = spectrogram(app.Bird_filtered, window(@hann, 1000), 500, 1024, app.Fs,'yaxis');
%             axes(app.UIAxes_spectrogram);
%             spectrogram(app.Bird_filtered, window(@hann, 1000), 500, 1024, app.Fs,'yaxis');
        end

        % Button pushed function: FasterversionsimilartothebirdsoundButton
        function FasterversionsimilartothebirdsoundButtonPushed(app, event)
%             if app.FasterversionsimilartothebirdsoundButton.Text == ...
%                 "Faster version (similar to the bird sound)"
            tempos = app.beats*0.07;
            app.piano_fast = PlayPiano(app.notes, app.Fs, tempos);
            soundsc(app.piano_fast, app.Fs);
            N_sig = length(app.piano_fast);
            t_piano = 0:1/app.Fs:(N_sig-1)/app.Fs;
            plot(app.UIAxes_piano, t_piano, app.piano_fast);
            disp("Faster version comparison.");
%                 app.FasterversionsimilartothebirdsoundButton.Text = "Stop";
%             elseif app.FasterversionsimilartothebirdsoundButton.Text == "Stop"
%                 clear sound;
%                 app.FasterversionsimilartothebirdsoundButton.Text = ...
%                     "Faster version (similar to the bird sound)";
%             end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.BirdsounddenoisingTab = uitab(app.TabGroup);
            app.BirdsounddenoisingTab.Title = 'Bird sound & denoising';
            app.UIAxes_sig_t = uiaxes(app.BirdsounddenoisingTab);
            title(app.UIAxes_sig_t, 'Sound signal')
            xlabel(app.UIAxes_sig_t, 'Time (s)')
            ylabel(app.UIAxes_sig_t, 'Amplitude')
            app.UIAxes_sig_f = uiaxes(app.BirdsounddenoisingTab);
            title(app.UIAxes_sig_f, 'Spectrum')
            xlabel(app.UIAxes_sig_f, 'Frequency (Hz)')
            ylabel(app.UIAxes_sig_f, 'Amplitude')
            app.ChoosesignalLabel = uilabel(app.BirdsounddenoisingTab);
            app.ChoosesignalLabel.Text = 'Choose signal';
            app.ChoosesignalDropDown = uidropdown(app.BirdsounddenoisingTab);
            app.ChoosesignalDropDown.Items = {'Not selected', 'Original', 'Ideal BPF', 'FIR BPF', 'Scalart (Wiener) Filter'};
            app.ChoosesignalDropDown.ValueChangedFcn = createCallbackFcn(app, @ChoosesignalDropDownValueChanged, true);
            app.ChoosesignalDropDown.Value = 'Not selected';
            app.PlaythesoundButton = uibutton(app.BirdsounddenoisingTab, 'push');
            app.PlaythesoundButton.ButtonPushedFcn = createCallbackFcn(app, @PlaythesoundButtonPushed, true);
            app.PlaythesoundButton.Text = 'Play the sound';
            app.StopButton = uibutton(app.BirdsounddenoisingTab, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.AudioloadButton = uibutton(app.BirdsounddenoisingTab, 'push');
            app.AudioloadButton.ButtonPushedFcn = createCallbackFcn(app, @AudioloadButtonPushed, true);
            app.AudioloadButton.Text = 'Audio load';
            app.PianonotesmappingTab = uitab(app.TabGroup);
            app.PianonotesmappingTab.Title = 'Piano notes mapping';
            app.UIAxes_fmax = uiaxes(app.PianonotesmappingTab);
            xlabel(app.UIAxes_fmax, 'Time (s)')
            ylabel(app.UIAxes_fmax, 'Frequency (Hz)')
            app.UIAxes_fmax.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes_notes = uiaxes(app.PianonotesmappingTab);
            xlabel(app.UIAxes_notes, 'Time (s)')
            ylabel(app.UIAxes_notes, 'Piano note number')
            app.UIAxes_notes.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.ExtractthefundamentalfrequencyButton = uibutton(app.PianonotesmappingTab, 'push');
            app.ExtractthefundamentalfrequencyButton.ButtonPushedFcn = createCallbackFcn(app, @ExtractthefundamentalfrequencyButtonPushed, true);
            app.ExtractthefundamentalfrequencyButton.Text = {'Extract the'; 'fundamental frequency'};
            app.MaptopianofrequencyButton = uibutton(app.PianonotesmappingTab, 'push');
            app.MaptopianofrequencyButton.ButtonPushedFcn = createCallbackFcn(app, @MaptopianofrequencyButtonPushed, true);
            app.MaptopianofrequencyButton.Text = {'Map to'; 'piano frequency'};
            app.GeneratenotesbeatsButton = uibutton(app.PianonotesmappingTab, 'push');
            app.GeneratenotesbeatsButton.ButtonPushedFcn = createCallbackFcn(app, @GeneratenotesbeatsButtonPushed, true);
            app.GeneratenotesbeatsButton.Text = 'Generate notes & beats';
            app.NotesTextAreaLabel = uilabel(app.PianonotesmappingTab);
            app.NotesTextAreaLabel.Text = 'Notes';
            app.NotesTextArea = uitextarea(app.PianonotesmappingTab);
            app.BeatsTextAreaLabel = uilabel(app.PianonotesmappingTab);
            app.BeatsTextAreaLabel.Text = 'Beats';
            app.BeatsTextArea = uitextarea(app.PianonotesmappingTab);
            app.PlaythemelodyTab = uitab(app.TabGroup);
            app.PlaythemelodyTab.Title = 'Play the melody';
            app.UIAxes_piano = uiaxes(app.PlaythemelodyTab);
            title(app.UIAxes_piano, 'Piano sound')
            xlabel(app.UIAxes_piano, 'Time (s)')
            ylabel(app.UIAxes_piano, 'Amplitude')
            app.PlaythepianoButton = uibutton(app.PlaythemelodyTab, 'push');
            app.PlaythepianoButton.ButtonPushedFcn = createCallbackFcn(app, @PlaythepianoButtonPushed, true);
            app.PlaythepianoButton.Text = 'Play the piano!';
            app.FasterversionsimilartothebirdsoundButton = uibutton(app.PlaythemelodyTab, 'push');
            app.FasterversionsimilartothebirdsoundButton.ButtonPushedFcn = createCallbackFcn(app, @FasterversionsimilartothebirdsoundButtonPushed, true);
            app.FasterversionsimilartothebirdsoundButton.Text = 'Faster version (similar to the bird sound)';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mainAPP

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end